classdef auto_corr_gen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ElongationTimesEditFieldLabel   matlab.ui.control.Label
        ElongationTimesEditField        matlab.ui.control.NumericEditField
        TimeResolutionsEditFieldLabel   matlab.ui.control.Label
        TimeResolutionsEditField        matlab.ui.control.NumericEditField
        LengthofTracespointsEditFieldLabel  matlab.ui.control.Label
        LengthofTracespointsEditField   matlab.ui.control.NumericEditField
        NumberofTracesEditFieldLabel    matlab.ui.control.Label
        NumberofTracesEditField         matlab.ui.control.NumericEditField
        UITable                         matlab.ui.control.Table
        NumberofStatesEditFieldLabel    matlab.ui.control.Label
        NumberofStatesEditField         matlab.ui.control.NumericEditField
        TransitionRateMatrixflowsLabel  matlab.ui.control.Label
        LengthofTracessLabel            matlab.ui.control.Label
        LengthTraceNum                  matlab.ui.control.Label
        fluoRNAEditFieldLabel           matlab.ui.control.Label
        fluoRNAEditField                matlab.ui.control.NumericEditField
        UITable2                        matlab.ui.control.Table
        EmissionRatesRNAsLabel          matlab.ui.control.Label
        GenerateListBoxLabel            matlab.ui.control.Label
        GenerateListBox                 matlab.ui.control.ListBox
        BootstrapsCheckBox              matlab.ui.control.CheckBox
        LoadPlotsButton                 matlab.ui.control.Button
        GeneratePlotsButton             matlab.ui.control.Button
        SavePlotsButton                 matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        Tab2                            matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        Tab_2                           matlab.ui.container.Tab
        Tab2_2                          matlab.ui.container.Tab
        TabGroup2_2                     matlab.ui.container.TabGroup
        Tab_3                           matlab.ui.container.Tab
        Tab2_3                          matlab.ui.container.Tab
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data = [-0.0115,  0.0095,  0.0000; ...
                                 0.0115, -0.0180,  0.0440; ...
                                 0.0000,  0.0085, -0.0440];
            app.UITable2.Data = [3,65,130];
        end

        % Callback function
        function UITableCellEdit(app, event)
            
        end

        % Value changed function: NumberofStatesEditField
        function NumberofStatesEditFieldValueChanged(app, event)
            value = app.NumberofStatesEditField.Value;
            cols = cell(value);
            for i = 1:value
                cols{i} = "State" + num2str(i);
            end
            %resets transition matrix table
            app.UITable.ColumnName = cols;
            app.UITable.Data = zeros(value,value);
            
            %resets emissionr rates table
            app.UITable2.ColumnName = cols;
            app.UITable2.Data = zeros(1,value);
        end

        % Value changed function: LengthofTracespointsEditField
        function LengthofTracespointsEditFieldValueChanged(app, event)
            traceLength = app.LengthofTracespointsEditField.Value;
            timeRes = app.TimeResolutionsEditField.Value;
            app.LengthTraceNum.Text = num2str(traceLength * timeRes);
        end

        % Value changed function: TimeResolutionsEditField
        function TimeResolutionsEditFieldValueChanged(app, event)
            traceLength = app.LengthofTracespointsEditField.Value;
            timeRes = app.TimeResolutionsEditField.Value;
            app.LengthTraceNum.Text = num2str(traceLength * timeRes);
        end

        % Button pushed function: LoadPlotsButton
        function LoadPlotsButtonPushed(app, event)
            [filename,pathname] = uigetfile;
            filepath = [pathname filename];
            fig = openfig(filepath);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ElongationTimesEditFieldLabel = uilabel(app.UIFigure);
            app.ElongationTimesEditFieldLabel.Text = 'Elongation Time (s)';
            app.ElongationTimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElongationTimesEditField.Limits = [0 Inf];
            app.ElongationTimesEditField.Value = 160;
            app.TimeResolutionsEditFieldLabel = uilabel(app.UIFigure);
            app.TimeResolutionsEditFieldLabel.Text = 'Time Resolution (s)';
            app.TimeResolutionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeResolutionsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeResolutionsEditFieldValueChanged, true);
            app.TimeResolutionsEditField.Value = 40;
            app.LengthofTracespointsEditFieldLabel = uilabel(app.UIFigure);
            app.LengthofTracespointsEditFieldLabel.Text = 'Length of Traces (points)';
            app.LengthofTracespointsEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthofTracespointsEditField.Limits = [1 Inf];
            app.LengthofTracespointsEditField.ValueDisplayFormat = '%.0f';
            app.LengthofTracespointsEditField.ValueChangedFcn = createCallbackFcn(app, @LengthofTracespointsEditFieldValueChanged, true);
            app.LengthofTracespointsEditField.Value = 50;
            app.NumberofTracesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofTracesEditFieldLabel.Text = 'Number of Traces ';
            app.NumberofTracesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofTracesEditField.Limits = [1 Inf];
            app.NumberofTracesEditField.ValueDisplayFormat = '%.0f';
            app.NumberofTracesEditField.Value = 50;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'State 1'; 'State 2'; 'State 3'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.NumberofStatesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofStatesEditFieldLabel.Text = 'Number of States';
            app.NumberofStatesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofStatesEditField.Limits = [0 Inf];
            app.NumberofStatesEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofStatesEditFieldValueChanged, true);
            app.NumberofStatesEditField.Value = 3;
            app.TransitionRateMatrixflowsLabel = uilabel(app.UIFigure);
            app.TransitionRateMatrixflowsLabel.Text = 'Transition Rate Matrix (flow/s)';
            app.LengthofTracessLabel = uilabel(app.UIFigure);
            app.LengthofTracessLabel.Text = 'Length of Traces (s)';
            app.LengthTraceNum = uilabel(app.UIFigure);
            app.LengthTraceNum.Text = '200';
            app.fluoRNAEditFieldLabel = uilabel(app.UIFigure);
            app.fluoRNAEditFieldLabel.Text = 'fluo/RNA';
            app.fluoRNAEditField = uieditfield(app.UIFigure, 'numeric');
            app.fluoRNAEditField.Limits = [0 Inf];
            app.fluoRNAEditField.Value = 350;
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'State 1'; 'State 2'; 'State 3'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.EmissionRatesRNAsLabel = uilabel(app.UIFigure);
            app.EmissionRatesRNAsLabel.Text = 'Emission Rates (RNA/s)';
            app.GenerateListBoxLabel = uilabel(app.UIFigure);
            app.GenerateListBoxLabel.Text = 'Generate';
            app.GenerateListBox = uilistbox(app.UIFigure);
            app.GenerateListBox.Items = {'Autocorrelation', 'First Derivative', 'Second Derivative'};
            app.GenerateListBox.Multiselect = 'on';
            app.GenerateListBox.Value = {'Autocorrelation'};
            app.BootstrapsCheckBox = uicheckbox(app.UIFigure);
            app.BootstrapsCheckBox.Text = 'Bootstraps';
            app.LoadPlotsButton = uibutton(app.UIFigure, 'push');
            app.LoadPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPlotsButtonPushed, true);
            app.LoadPlotsButton.Text = 'Load Plots';
            app.GeneratePlotsButton = uibutton(app.UIFigure, 'push');
            app.GeneratePlotsButton.Text = 'Generate Plots';
            app.SavePlotsButton = uibutton(app.UIFigure, 'push');
            app.SavePlotsButton.Text = 'Save Plots';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.Tab_2 = uitab(app.TabGroup2);
            app.Tab_2.Title = 'Tab';
            app.Tab2_2 = uitab(app.TabGroup2);
            app.Tab2_2.Title = 'Tab2';
            app.TabGroup2_2 = uitabgroup(app.UIFigure);
            app.Tab_3 = uitab(app.TabGroup2_2);
            app.Tab_3.Title = 'Tab';
            app.Tab2_3 = uitab(app.TabGroup2_2);
            app.Tab2_3.Title = 'Tab2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = auto_corr_gen

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end